classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ApplyButton             matlab.ui.control.Button
        ApplyStatus             matlab.ui.control.Label
        ResetSensorInformation  matlab.ui.control.Button
        SensorResetStatus       matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        S1Tab                   matlab.ui.container.Tab
        S1UnitsOutputLabel      matlab.ui.control.Label
        S1UnitsALabel           matlab.ui.control.Label
        S1UnitsBLabel           matlab.ui.control.Label
        S1EnableSensor          matlab.ui.control.CheckBox
        OutputLabel             matlab.ui.control.Label
        S1EditFieldOutput       matlab.ui.control.NumericEditField
        aLabel                  matlab.ui.control.Label
        S1EditFieldA            matlab.ui.control.NumericEditField
        bLabel                  matlab.ui.control.Label
        S1EditFieldB            matlab.ui.control.NumericEditField
        S2Tab                   matlab.ui.container.Tab
        S2UnitsOutputLabel      matlab.ui.control.Label
        S2UnitsALabel           matlab.ui.control.Label
        S2UnitsBLabel           matlab.ui.control.Label
        S2EnableSensor          matlab.ui.control.CheckBox
        OutputLabel_3           matlab.ui.control.Label
        S2EditFieldOutput       matlab.ui.control.NumericEditField
        aLabel_2                matlab.ui.control.Label
        S2EditFieldA            matlab.ui.control.NumericEditField
        bLabel_2                matlab.ui.control.Label
        S2EditFieldB            matlab.ui.control.NumericEditField
        S3Tab                   matlab.ui.container.Tab
        S3UnitsOutputLabel      matlab.ui.control.Label
        S3UnitsALabel           matlab.ui.control.Label
        S3UnitsBLabel           matlab.ui.control.Label
        S3EnableSensor          matlab.ui.control.CheckBox
        OutputLabel_4           matlab.ui.control.Label
        S3EditFieldOutput       matlab.ui.control.NumericEditField
        aLabel_3                matlab.ui.control.Label
        S3EditFieldA            matlab.ui.control.NumericEditField
        bLabel_3                matlab.ui.control.Label
        S3EditFieldB            matlab.ui.control.NumericEditField
        S4Tab                   matlab.ui.container.Tab
        S4UnitsOutputLabel      matlab.ui.control.Label
        S4UnitsALabel           matlab.ui.control.Label
        S4UnitsBLabel           matlab.ui.control.Label
        S4EnableSensor          matlab.ui.control.CheckBox
        OutputLabel_5           matlab.ui.control.Label
        S4EditFieldOutput       matlab.ui.control.NumericEditField
        aLabel_4                matlab.ui.control.Label
        S4EditFieldA            matlab.ui.control.NumericEditField
        bLabel_4                matlab.ui.control.Label
        S4EditFieldB            matlab.ui.control.NumericEditField
        S5Tab                   matlab.ui.container.Tab
        S5UnitsOutputLabel      matlab.ui.control.Label
        S5UnitsALabel           matlab.ui.control.Label
        S5UnitsBLabel           matlab.ui.control.Label
        S5EnableSensor          matlab.ui.control.CheckBox
        OutputLabel_6           matlab.ui.control.Label
        S5EditFieldOutput       matlab.ui.control.NumericEditField
        aLabel_5                matlab.ui.control.Label
        S5EditFieldA            matlab.ui.control.NumericEditField
        bLabel_5                matlab.ui.control.Label
        S5EditFieldB            matlab.ui.control.NumericEditField
        PumprateEditFieldLabel  matlab.ui.control.Label
        PumprateEditField       matlab.ui.control.NumericEditField
        PumprateUnitsLabel      matlab.ui.control.Label
        SelectdeviceLabel       matlab.ui.control.Label
        SelectCOMPort           matlab.ui.control.DropDown
        RefreshCOMPorts         matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        S1Graph                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        serialPortArduino % Description
    end

    methods (Access = private)
        function success = connectToArduino(app, comPort)
            success = true;
            if ~setSerialPort(comPort)
                success = false;
            end
            
            if ~openSerialChannel()
                success = false;
            end
        end
        
        function disableSensorParameterInput(app, sensor)
            switch(sensor)
                case 'S1'
                    app.S1EditFieldOutput.Enable = 'off';
                    app.S1EditFieldB.Enable = 'off';
                    app.S1EditFieldA.Enable = 'off';
                case 'S2'
                    app.S2EditFieldOutput.Enable = 'off';
                    app.S2EditFieldB.Enable = 'off';
                    app.S2EditFieldA.Enable = 'off';
                case 'S3'
                    app.S3EditFieldOutput.Enable = 'off';
                    app.S3EditFieldB.Enable = 'off';
                    app.S3EditFieldA.Enable = 'off';
                case 'S4'
                    app.S4EditFieldOutput.Enable = 'off';
                    app.S4EditFieldB.Enable = 'off';
                    app.S4EditFieldA.Enable = 'off';
                case 'S5'
                    app.S5EditFieldOutput.Enable = 'off';
                    app.S5EditFieldB.Enable = 'off';
                    app.S5EditFieldA.Enable = 'off';
            end
        end % disableSensorParameterInput
        
        function enableSensorParameterInput(app, sensor)
            switch(sensor)
                case 'S1'
                    app.S1EditFieldOutput.Enable = 'on';
                    app.S1EditFieldB.Enable = 'on';
                    app.S1EditFieldA.Enable = 'on';
                case 'S2'
                    app.S2EditFieldOutput.Enable = 'on';
                    app.S2EditFieldB.Enable = 'on';
                    app.S2EditFieldA.Enable = 'on';
                case 'S3'
                    app.S3EditFieldOutput.Enable = 'on';
                    app.S3EditFieldB.Enable = 'on';
                    app.S3EditFieldA.Enable = 'on';
                case 'S4'
                    app.S4EditFieldOutput.Enable = 'on';
                    app.S4EditFieldB.Enable = 'on';
                    app.S4EditFieldA.Enable = 'on';
                case 'S5'
                    app.S5EditFieldOutput.Enable = 'on';
                    app.S5EditFieldB.Enable = 'on';
                    app.S5EditFieldA.Enable = 'on';
            end
        end % enableSensorParameterInput
        
        function success = setSensors(app)
            success = true;
            if app.S1EnableSensor.Value
                 if ~setAnalogueSensor(1, app.S1EditFieldOutput.Value, ...
                         app.S1EditFieldA.Value, app.S1EditFieldB.Value)
                     success = false; % only set success to false when a failure occurs.
                 end
            else
                if ~setAnalogueSensor(1, -1, 0, 0) % Set output to -1 to indicate disabled sensor.
                    success = false;
                end
            end % S1
            
            if app.S2EnableSensor.Value
                 if ~setAnalogueSensor(2, app.S2EditFieldOutput.Value, ...
                         app.S2EditFieldA.Value, app.S2EditFieldB.Value)
                     success = false; % only set success to false when a failure occurs.
                 end
            else
                if ~setAnalogueSensor(2, -1, 0, 0)
                    success = false;
                end
            end % S2
            
            if app.S3EnableSensor.Value
                 if ~setAnalogueSensor(3, app.S3EditFieldOutput.Value, ...
                         app.S3EditFieldA.Value, app.S3EditFieldB.Value)
                     success = false; % only set success to false when a failure occurs.
                 end
            else
                if ~setAnalogueSensor(3, -1, 0, 0)
                    success = false;
                end
            end % S3
            
            if app.S4EnableSensor.Value
                 if ~setAnalogueSensor(4, app.S4EditFieldOutput.Value, ...
                         app.S4EditFieldA.Value, app.S4EditFieldB.Value)
                     success = false; % only set success to false when a failure occurs.
                 end
            else
                if ~setAnalogueSensor(4, -1, 0, 0)
                    success = false;
                end
            end % S4
            
            if app.S5EnableSensor.Value
                 if ~setAnalogueSensor(5, app.S5EditFieldOutput.Value, ...
                         app.S5EditFieldA.Value, app.S5EditFieldB.Value)
                     success = false; % only set success to false when a failure occurs.
                 end
            else
                if ~setAnalogueSensor(5, -1, 0, 0)
                    success = false;
                end
            end % S5
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ApplyStatus.Visible = 'off';
            app.SensorResetStatus.Visible = 'off';
            assignin('base', 'app', app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            success = true;
            if ~setSensors(app)
                success = false;
            end
            
            if ~setPumps(app.PumprateEditField.Value)
                success = false;
            end
            
%             connectToArduino(app, app.SelectCOMPort.Value);
            
            app.ApplyStatus.Visible = 'on';
            if success
                app.ApplyStatus.Text = 'Success!';
            else
                app.ApplyStatus.Text = 'Failed!';
            end
        end

        % Value changed function: S1EnableSensor
        function S1EnableSensorValueChanged(app, event)
            if app.S1EnableSensor.Value
                enableSensorParameterInput(app, 'S1');
            else
                disableSensorParameterInput(app, 'S1');
            end
        end

        % Value changed function: S2EnableSensor
        function S2EnableSensorValueChanged(app, event)
            if app.S2EnableSensor.Value
                enableSensorParameterInput(app, 'S2');
            else
                disableSensorParameterInput(app, 'S2');
            end
        end

        % Button pushed function: ResetSensorInformation
        function ResetSensorInformationButtonPushed(app, event)
            app.SensorResetStatus.Visible = 'on';
            if evalin('base', 'exist(''Sensors'')')
                evalin('base', 'clear setSensor'); % Delete the previous sensor information
                evalin('base', 'clear Sensors'); % Delete the previous sensor information
                if evalin('base', 'exist(''Sensors'')')
                    app.SensorResetStatus.Text = 'Failed!';
                    warning('Failed to remove old instance of sensor information.');
                else
                    app.SensorResetStatus.Text = 'Success!';
                end
            else
                app.SensorResetStatus.Text = 'Success!';
            end
        end

        % Button pushed function: RefreshCOMPorts
        function RefreshCOMPortsButtonPushed(app, event)
            serialDevices = seriallist;
            numberOfDevices = size(serialDevices,2);
            if numberOfDevices < 1
                app.SelectCOMPort.Items = {'No devices'};
            else
                for i = 1:numberOfDevices
                   tempCell{i} = convertStringsToChars(serialDevices(i));
                end
                app.SelectCOMPort.Items = tempCell;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            status = evalin('base', 'exist(''arduinoSerial'')');
            if status % Close if the channel has been created.
                evalin('base', 'fclose(arduinoSerial)'); % Close the channel that communicates with Arduino, can still open afterwards.
                evalin('base', 'delete(arduinoSerial)'); % Releases the channel such that the COM port can be used again.
                evalin('base', 'clear(''arduinoSerial'')'); % Removes the variable from the workspace since it cannot be opened again after delete.
            end
            status = evalin('base', 'exist(''sensorData'')');
            if status % Only write to file when sensor data has been collected.
                tempString = strcat('csvwrite(','''../Output/', datestr(now, 'yyyy-mm-dd_HHMMSS'), '_sensorData.csv'', sensorData);');
                evalin('base', tempString);
                evalin('base', 'clear(''sensorData'')');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            StopButtonPushed(app, event);
        end

        % Value changed function: S3EnableSensor
        function S3EnableSensorValueChanged(app, event)
            if app.S3EnableSensor.Value
                enableSensorParameterInput(app, 'S3');
            else
                disableSensorParameterInput(app, 'S3');
            end
        end

        % Value changed function: S4EnableSensor
        function S4EnableSensorValueChanged(app, event)
            if app.S4EnableSensor.Value
                enableSensorParameterInput(app, 'S4');
            else
                disableSensorParameterInput(app, 'S4');
            end
        end

        % Value changed function: S5EnableSensor
        function S5EnableSensorValueChanged(app, event)
            if app.S5EnableSensor.Value
                enableSensorParameterInput(app, 'S5');
            else
                disableSensorParameterInput(app, 'S5');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ApplyStatus = uilabel(app.UIFigure);
            app.ApplyStatus.Text = 'Success!';
            app.ResetSensorInformation = uibutton(app.UIFigure, 'push');
            app.ResetSensorInformation.ButtonPushedFcn = createCallbackFcn(app, @ResetSensorInformationButtonPushed, true);
            app.ResetSensorInformation.Text = 'Reset sensors';
            app.SensorResetStatus = uilabel(app.UIFigure);
            app.SensorResetStatus.Text = 'Success!';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.S1Tab = uitab(app.TabGroup);
            app.S1Tab.Title = 'S1';
            app.S1UnitsOutputLabel = uilabel(app.S1Tab);
            app.S1UnitsOutputLabel.Text = 'Volt';
            app.S1UnitsALabel = uilabel(app.S1Tab);
            app.S1UnitsALabel.Text = 'V/unit';
            app.S1UnitsBLabel = uilabel(app.S1Tab);
            app.S1UnitsBLabel.Text = 'Volt';
            app.S1EnableSensor = uicheckbox(app.S1Tab);
            app.S1EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S1EnableSensorValueChanged, true);
            app.S1EnableSensor.Text = 'Enable';
            app.OutputLabel = uilabel(app.S1Tab);
            app.OutputLabel.Enable = 'off';
            app.OutputLabel.Text = 'Output:';
            app.S1EditFieldOutput = uieditfield(app.S1Tab, 'numeric');
            app.S1EditFieldOutput.Limits = [0 Inf];
            app.S1EditFieldOutput.Enable = 'off';
            app.aLabel = uilabel(app.S1Tab);
            app.aLabel.Enable = 'off';
            app.aLabel.Text = 'a:';
            app.S1EditFieldA = uieditfield(app.S1Tab, 'numeric');
            app.S1EditFieldA.Limits = [0 Inf];
            app.S1EditFieldA.Enable = 'off';
            app.bLabel = uilabel(app.S1Tab);
            app.bLabel.Enable = 'off';
            app.bLabel.Text = 'b:';
            app.S1EditFieldB = uieditfield(app.S1Tab, 'numeric');
            app.S1EditFieldB.Limits = [0 Inf];
            app.S1EditFieldB.Enable = 'off';
            app.S2Tab = uitab(app.TabGroup);
            app.S2Tab.Title = 'S2';
            app.S2UnitsOutputLabel = uilabel(app.S2Tab);
            app.S2UnitsOutputLabel.Text = 'Volt';
            app.S2UnitsALabel = uilabel(app.S2Tab);
            app.S2UnitsALabel.Text = 'V/unit';
            app.S2UnitsBLabel = uilabel(app.S2Tab);
            app.S2UnitsBLabel.Text = 'Volt';
            app.S2EnableSensor = uicheckbox(app.S2Tab);
            app.S2EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S2EnableSensorValueChanged, true);
            app.S2EnableSensor.Text = 'Enable';
            app.OutputLabel_3 = uilabel(app.S2Tab);
            app.OutputLabel_3.Enable = 'off';
            app.OutputLabel_3.Text = 'Output:';
            app.S2EditFieldOutput = uieditfield(app.S2Tab, 'numeric');
            app.S2EditFieldOutput.Limits = [0 Inf];
            app.S2EditFieldOutput.Enable = 'off';
            app.aLabel_2 = uilabel(app.S2Tab);
            app.aLabel_2.Enable = 'off';
            app.aLabel_2.Text = 'a:';
            app.S2EditFieldA = uieditfield(app.S2Tab, 'numeric');
            app.S2EditFieldA.Limits = [0 Inf];
            app.S2EditFieldA.Enable = 'off';
            app.bLabel_2 = uilabel(app.S2Tab);
            app.bLabel_2.Enable = 'off';
            app.bLabel_2.Text = 'b:';
            app.S2EditFieldB = uieditfield(app.S2Tab, 'numeric');
            app.S2EditFieldB.Limits = [0 Inf];
            app.S2EditFieldB.Enable = 'off';
            app.S3Tab = uitab(app.TabGroup);
            app.S3Tab.Title = 'S3';
            app.S3UnitsOutputLabel = uilabel(app.S3Tab);
            app.S3UnitsOutputLabel.Text = 'Volt';
            app.S3UnitsALabel = uilabel(app.S3Tab);
            app.S3UnitsALabel.Text = 'V/unit';
            app.S3UnitsBLabel = uilabel(app.S3Tab);
            app.S3UnitsBLabel.Text = 'Volt';
            app.S3EnableSensor = uicheckbox(app.S3Tab);
            app.S3EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S3EnableSensorValueChanged, true);
            app.S3EnableSensor.Text = 'Enable';
            app.OutputLabel_4 = uilabel(app.S3Tab);
            app.OutputLabel_4.Enable = 'off';
            app.OutputLabel_4.Text = 'Output:';
            app.S3EditFieldOutput = uieditfield(app.S3Tab, 'numeric');
            app.S3EditFieldOutput.Limits = [0 Inf];
            app.S3EditFieldOutput.Enable = 'off';
            app.aLabel_3 = uilabel(app.S3Tab);
            app.aLabel_3.Enable = 'off';
            app.aLabel_3.Text = 'a:';
            app.S3EditFieldA = uieditfield(app.S3Tab, 'numeric');
            app.S3EditFieldA.Limits = [0 Inf];
            app.S3EditFieldA.Enable = 'off';
            app.bLabel_3 = uilabel(app.S3Tab);
            app.bLabel_3.Enable = 'off';
            app.bLabel_3.Text = 'b:';
            app.S3EditFieldB = uieditfield(app.S3Tab, 'numeric');
            app.S3EditFieldB.Limits = [0 Inf];
            app.S3EditFieldB.Enable = 'off';
            app.S4Tab = uitab(app.TabGroup);
            app.S4Tab.Title = 'S4';
            app.S4UnitsOutputLabel = uilabel(app.S4Tab);
            app.S4UnitsOutputLabel.Text = 'Volt';
            app.S4UnitsALabel = uilabel(app.S4Tab);
            app.S4UnitsALabel.Text = 'V/unit';
            app.S4UnitsBLabel = uilabel(app.S4Tab);
            app.S4UnitsBLabel.Text = 'Volt';
            app.S4EnableSensor = uicheckbox(app.S4Tab);
            app.S4EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S4EnableSensorValueChanged, true);
            app.S4EnableSensor.Text = 'Enable';
            app.OutputLabel_5 = uilabel(app.S4Tab);
            app.OutputLabel_5.Enable = 'off';
            app.OutputLabel_5.Text = 'Output:';
            app.S4EditFieldOutput = uieditfield(app.S4Tab, 'numeric');
            app.S4EditFieldOutput.Limits = [0 Inf];
            app.S4EditFieldOutput.Enable = 'off';
            app.aLabel_4 = uilabel(app.S4Tab);
            app.aLabel_4.Enable = 'off';
            app.aLabel_4.Text = 'a:';
            app.S4EditFieldA = uieditfield(app.S4Tab, 'numeric');
            app.S4EditFieldA.Limits = [0 Inf];
            app.S4EditFieldA.Enable = 'off';
            app.bLabel_4 = uilabel(app.S4Tab);
            app.bLabel_4.Enable = 'off';
            app.bLabel_4.Text = 'b:';
            app.S4EditFieldB = uieditfield(app.S4Tab, 'numeric');
            app.S4EditFieldB.Limits = [0 Inf];
            app.S4EditFieldB.Enable = 'off';
            app.S5Tab = uitab(app.TabGroup);
            app.S5Tab.Title = 'S5';
            app.S5UnitsOutputLabel = uilabel(app.S5Tab);
            app.S5UnitsOutputLabel.Text = 'Volt';
            app.S5UnitsALabel = uilabel(app.S5Tab);
            app.S5UnitsALabel.Text = 'V/unit';
            app.S5UnitsBLabel = uilabel(app.S5Tab);
            app.S5UnitsBLabel.Text = 'Volt';
            app.S5EnableSensor = uicheckbox(app.S5Tab);
            app.S5EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S5EnableSensorValueChanged, true);
            app.S5EnableSensor.Text = 'Enable';
            app.OutputLabel_6 = uilabel(app.S5Tab);
            app.OutputLabel_6.Enable = 'off';
            app.OutputLabel_6.Text = 'Output:';
            app.S5EditFieldOutput = uieditfield(app.S5Tab, 'numeric');
            app.S5EditFieldOutput.Limits = [0 Inf];
            app.S5EditFieldOutput.Enable = 'off';
            app.aLabel_5 = uilabel(app.S5Tab);
            app.aLabel_5.Enable = 'off';
            app.aLabel_5.Text = 'a:';
            app.S5EditFieldA = uieditfield(app.S5Tab, 'numeric');
            app.S5EditFieldA.Limits = [0 Inf];
            app.S5EditFieldA.Enable = 'off';
            app.bLabel_5 = uilabel(app.S5Tab);
            app.bLabel_5.Enable = 'off';
            app.bLabel_5.Text = 'b:';
            app.S5EditFieldB = uieditfield(app.S5Tab, 'numeric');
            app.S5EditFieldB.Limits = [0 Inf];
            app.S5EditFieldB.Enable = 'off';
            app.PumprateEditFieldLabel = uilabel(app.UIFigure);
            app.PumprateEditFieldLabel.Text = 'Pump rate:';
            app.PumprateEditField = uieditfield(app.UIFigure, 'numeric');
            app.PumprateUnitsLabel = uilabel(app.UIFigure);
            app.PumprateUnitsLabel.Text = 'L/min';
            app.SelectdeviceLabel = uilabel(app.UIFigure);
            app.SelectdeviceLabel.Text = 'Select device:';
            app.SelectCOMPort = uidropdown(app.UIFigure);
            app.SelectCOMPort.Items = {'No devices'};
            app.SelectCOMPort.Value = 'No devices';
            app.RefreshCOMPorts = uibutton(app.UIFigure, 'push');
            app.RefreshCOMPorts.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMPortsButtonPushed, true);
            app.RefreshCOMPorts.Text = 'Refresh';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.S1Graph = uiaxes(app.UIFigure);
            title(app.S1Graph, 'Flow')
            xlabel(app.S1Graph, 't')
            ylabel(app.S1Graph, 'L/min')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Pressure')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'mmHg')
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end